classdef PlantSeg2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        ActiveMaskValueButtonGroup   matlab.ui.container.ButtonGroup
        RootButton                   matlab.ui.control.ToggleButton
        ShootButton                  matlab.ui.control.ToggleButton
        BackgroundButton             matlab.ui.control.ToggleButton
        SliderMaskAlpha              matlab.ui.control.Slider
        MaskAlphaLabel               matlab.ui.control.Label
        OpenDirButton                matlab.ui.control.Button
        MiscTab                      matlab.ui.container.Tab
        rootLabel                    matlab.ui.control.Label
        shootLabel                   matlab.ui.control.Label
        ColorSelectorMaskRoot        wt.ColorSelector
        ColorSelectorMaskShoot       wt.ColorSelector
        backgroundLabel              matlab.ui.control.Label
        ColorSelectorMaskBackground  wt.ColorSelector
        ShowmaskbackgroundcolorCheckBox  matlab.ui.control.CheckBox
        UIAxesTest                   matlab.ui.control.UIAxes
        UIAxesSeg                    matlab.ui.control.UIAxes
    end

    
    % PPrivate variables:
    properties (Access = private)
        fixedImageWidth = 995;      % All images will be set to this width for consistency.
        fixedImageHeight = 1016;    % All images will be set to this height for consistency.
        bgMaskValue = 0;            % Value of background pixels in mask image.
        shootMaskValue = 127;       % Value of shoot pixels in mask image.
        rootMaskValue = 255;        % Value of root pixels in mask image.
        activeMaskValue = 0;        % Value to be painted in mask using mouse.
        bgColor = [0, 0, 0];        % Color for diplaying background pixels.
        shootColor = [0, 0, 1];     % Color for diplaying shoot pixels.
        rootColor = [1, 0, 0];      % Color for diplaying root pixels.
        segColorMap = 0;
        inputImage = 0;             % Input image with 3 channels RGB of uint8.
        mask = 0;                   % Output mask (uint8 single channel).
        maskAlpha = 0.5;            % Mask transparency value.
        imagePlot = 0;              % Handle to main image plot.
        showBackgroundColor = false;% Toggle display of background color.

        binaryMask = 0;
        mouseDown = false;          % Is left mouse button currently pressed?
    end
    
    % Private functions:
    methods (Access = private)
        
        function UpdateSegmentation(app)
            imagesc(app.inputImage, "Parent", app.UIAxesSeg);
            hold(app.UIAxesSeg, "on");
            app.imagePlot = imagesc(app.mask, "Parent", app.UIAxesSeg);
            colormap(app.UIAxesSeg, app.segColorMap);
            app.UIAxesSeg.XLim = [0 app.imagePlot.XData(2)];
            app.UIAxesSeg.YLim = [0 app.imagePlot.YData(2)];
            hold(app.UIAxesSeg, "off")
            
            alphaImage = ComputeMaskAlphaImage(app);
            set(app.imagePlot, 'AlphaData', alphaImage);
            set(app.imagePlot, 'Tag', 'WindowTag')
        end
        
        function InitializeVariables(app)
            app.SliderMaskAlpha.Value = app.maskAlpha;
            app.ShowmaskbackgroundcolorCheckBox.Value = app.showBackgroundColor;
            app.ColorSelectorMaskBackground.Value = app.bgColor;
            app.ColorSelectorMaskShoot.Value = app.shootColor;
            app.ColorSelectorMaskRoot.Value = app.rootColor;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor];
        end
        
        function ComputeMaskFromInput(app)
            app.mask = rgb2gray(app.inputImage);
            app.mask = (uint8(app.mask>=100 & app.mask<=149)*127) + (uint8(app.mask>=150 & app.mask<=255)*255);
        end
        
        function UpdateMaskFromMouse(app, event)
            mouseButtonFlag = get(event.Source,'SelectionType');
            mousePos = get(app.UIAxesSeg,'CurrentPoint');
            mouseRowPos = round(mousePos(1,2));
            mouseColPos = round(mousePos(1,1));

            if(strcmp(mouseButtonFlag, 'normal'))
                app.mask(mouseRowPos, mouseColPos) = app.activeMaskValue;
            else
                app.mask(mouseRowPos, mouseColPos) = app.bgMaskValue;
            end
            alphaImage = ComputeMaskAlphaImage(app);
            set(app.imagePlot, 'CData', app.mask, 'alphadata', alphaImage);
        end
        
        function alphaImage = ComputeMaskAlphaImage(app)
            if app.showBackgroundColor
                alphaImage = ones(size(app.mask)) * app.maskAlpha;
            else
                alphaImage = (single(app.mask > app.bgMaskValue)) * app.maskAlpha;
            end
        end
        
        function ApplySegmentation(app)
            
            imageIn = histeq(app.inputImage);
            app.binaryMask = imageIn(:,:,2) >= 75;
            
            ycbrImage = rgb2ycbcr(imageIn);
            app.binaryMask = app.binaryMask & ycbrImage(:,:,1) > 75;

            labImage = rgb2lab(imageIn);
            app.binaryMask = app.binaryMask & ((labImage(:,:,3) >= -11.867) & (labImage(:,:,3) <= 49.663));
            app.binaryMask = imclearborder(app.binaryMask, 8);
            imshow(app.binaryMask, 'Parent', app.UIAxesTest);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenDirButton
        function OpenDirButtonPushed(app, event)
            
            app.inputImage = imread("00220110719002.tif");
            app.inputImage = imresize(app.inputImage, [app.fixedImageHeight, app.fixedImageWidth]);

            ComputeMaskFromInput(app);
            InitializeVariables(app);
            UpdateSegmentation(app);
            ApplySegmentation(app);
        end

        % Value changed function: SliderMaskAlpha
        function SliderMaskAlphaValueChanged(app, event)
            value = app.SliderMaskAlpha.Value;
            app.maskAlpha = value;
            UpdateSegmentation(app);
        end

        % Value changed function: ShowmaskbackgroundcolorCheckBox
        function ShowmaskbackgroundcolorCheckBoxValueChanged(app, event)
            app.showBackgroundColor = app.ShowmaskbackgroundcolorCheckBox.Value;
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskBackground
        function ColorSelectorMaskBackgroundValueChanged(app, event)
            app.bgColor = app.ColorSelectorMaskBackground.Value;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor];
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskShoot
        function ColorSelectorMaskShootValueChanged(app, event)
            app.shootColor = app.ColorSelectorMaskShoot.Value;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor];
            UpdateSegmentation(app);
        end

        % Callback function: ColorSelectorMaskRoot
        function ColorSelectorMaskRootValueChanged(app, event)
            app.rootColor = app.ColorSelectorMaskRoot.Value;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor];
            UpdateSegmentation(app);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                app.mouseDown = true;
                UpdateMaskFromMouse(app, event);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                if(app.mouseDown)
                    UpdateMaskFromMouse(app, event);
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                app.mouseDown = false;
            end
        end

        % Selection changed function: ActiveMaskValueButtonGroup
        function ActiveMaskValueButtonGroupSelectionChanged(app, event)
            selectedButton = app.ActiveMaskValueButtonGroup.SelectedObject;
            if(strcmp(selectedButton.Text, "Background"))
                app.activeMaskValue = app.bgMaskValue;
            end
            if(strcmp(selectedButton.Text, "Root"))
                app.activeMaskValue = app.rootMaskValue;
            end
            if(strcmp(selectedButton.Text, "Shoot"))
                app.activeMaskValue = app.shootMaskValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxesSeg = uiaxes(app.UIFigure);
            app.UIAxesSeg.XTick = [];
            app.UIAxesSeg.YTick = [];
            app.UIAxesTest = uiaxes(app.UIFigure);
            title(app.UIAxesTest, 'Title')
            xlabel(app.UIAxesTest, 'X')
            ylabel(app.UIAxesTest, 'Y')
            zlabel(app.UIAxesTest, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.OpenDirButton = uibutton(app.MainTab, 'push');
            app.OpenDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirButtonPushed, true);
            app.OpenDirButton.Text = 'Open Dir';
            app.MaskAlphaLabel = uilabel(app.MainTab);
            app.MaskAlphaLabel.Text = 'Mask Alpha';
            app.SliderMaskAlpha = uislider(app.MainTab);
            app.SliderMaskAlpha.Limits = [0 1];
            app.SliderMaskAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderMaskAlphaValueChanged, true);
            app.ActiveMaskValueButtonGroup = uibuttongroup(app.MainTab);
            app.ActiveMaskValueButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ActiveMaskValueButtonGroupSelectionChanged, true);
            app.ActiveMaskValueButtonGroup.Title = 'Active Mask Value';
            app.BackgroundButton = uitogglebutton(app.ActiveMaskValueButtonGroup);
            app.BackgroundButton.Text = 'Background';
            app.BackgroundButton.Value = true;
            app.ShootButton = uitogglebutton(app.ActiveMaskValueButtonGroup);
            app.ShootButton.Text = 'Shoot';
            app.RootButton = uitogglebutton(app.ActiveMaskValueButtonGroup);
            app.RootButton.Text = 'Root';
            app.MiscTab = uitab(app.TabGroup);
            app.MiscTab.Title = 'Misc';
            app.ShowmaskbackgroundcolorCheckBox = uicheckbox(app.MiscTab);
            app.ShowmaskbackgroundcolorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmaskbackgroundcolorCheckBoxValueChanged, true);
            app.ShowmaskbackgroundcolorCheckBox.Text = 'Show mask background color';
            app.ColorSelectorMaskBackground = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskBackground.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskBackgroundValueChanged, true);
            app.backgroundLabel = uilabel(app.MiscTab);
            app.backgroundLabel.Text = 'background';
            app.ColorSelectorMaskShoot = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskShoot.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskShootValueChanged, true);
            app.ColorSelectorMaskRoot = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskRoot.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskRootValueChanged, true);
            app.shootLabel = uilabel(app.MiscTab);
            app.shootLabel.Text = 'shoot';
            app.rootLabel = uilabel(app.MiscTab);
            app.rootLabel.Text = 'root';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantSeg2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end