classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        Button      matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        UIAxesSeg   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        bgMaskValue = 0;    
        shootMaskValue = 127;
        rootMaskValue = 255;
        bgColor = [0, 0, 0];
        shootColor = [0, 0, 1];
        rootColor = [1, 0, 0];
        inputImage = 0;         % input image with 3 channels RGB of uint8
        mask = 0;               % uint8 mask
        maskAlpha = 0.5;        % mask transparency
        imagePlot = 0;
        showBackgroundColor = false;
        
        mouseDown = false;
    end
    
    methods (Access = private)
        
        function UpdateSegmentation(app)

             imshow(app.inputImage, "Parent", app.UIAxesSeg, "Border", "tight", "InitialMagnification", "fit");
            hold(app.UIAxesSeg, "on");
            app.imagePlot = imshow(app.mask, "Parent", app.UIAxesSeg, "Border", "tight", "InitialMagnification", "fit");
            segColorMap = [app.bgColor; app.shootColor; app.rootColor];
            colormap(app.UIAxesSeg, segColorMap);
            hold(app.UIAxesSeg, "off")
            
            alphaImage = ComputeMaskAlphaImage(app);
            set(app.imagePlot, 'AlphaData', alphaImage);
            set(app.imagePlot, 'Tag', 'WindowTag')
        end
        
        function InitializeVariables(app)
            %app.SliderMaskAlpha.Value = app.maskAlpha;
            %app.ShowmaskbackgroundcolorCheckBox.Value = app.showBackgroundColor;
            %app.ColorSelectorMaskBackground.Value = app.bgColor;
            %app.ColorSelectorMaskShoot.Value = app.shootColor;
            %app.ColorSelectorMaskRoot.Value = app.rootColor;
        end
        
        function ComputeMaskFromInput(app)
            app.mask = rgb2gray(app.inputImage);
            app.mask = (uint8(app.mask>=100 & app.mask<=149)*127) + (uint8(app.mask>=150 & app.mask<=255)*255);
        end

        function alphaImage = ComputeMaskAlphaImage(app)
            if app.showBackgroundColor
                alphaImage = ones(size(app.mask)) * app.maskAlpha;
            else
                alphaImage = (single(app.mask > app.bgMaskValue)) * app.maskAlpha;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.inputImage = imread("00220110719002.tif");
            
            ComputeMaskFromInput(app);
            InitializeVariables(app);
            UpdateSegmentation(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesSeg = uiaxes(app.RightPanel);
            title(app.UIAxesSeg, 'Title')
            xlabel(app.UIAxesSeg, 'X')
            ylabel(app.UIAxesSeg, 'Y')
            zlabel(app.UIAxesSeg, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end