classdef PlantSegOld < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Slider              matlab.ui.control.Slider
        SliderLabel         matlab.ui.control.Label
        OpenInputDirButton  matlab.ui.control.Button
        UIAxesInput         matlab.ui.control.UIAxes
        CenterPanel         matlab.ui.container.Panel
        RightPanel          matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        drawNow = 0; % Description
        mask = 0;
        maskAlpha = 0.5;
        inputImage = 0;
    end
    
    methods (Access = private)
        
        function result = GetFirstTifImageFilename(~, inputDir)
            listing = dir(inputDir);
            numFilenames = size(listing, 1)
            fileIndex = 1;
            while(fileIndex <= numFilenames) 
                filename = inputDir + "\" + listing(fileIndex).name

                if(isfile(filename))
                    test = 1
                    [filepath,name,ext] = fileparts(filename)
                    if(strcmp(ext,".tif"))
                        result = filename
                        return;
                    end
                end
                fileIndex = fileIndex + 1;
            end

            result = "";
        end
        
        function results = UpdateSegmentation(app)
            cla(app.UIAxesInput);
            %imshow(app.inputImage, "Parent", app.UIAxesInput);
            %hold(app.UIAxesInput, "on");
            h = imshow(app.mask, "Parent", app.UIAxesInput);
            %h = imagesc(app.mask, "Parent", app.UIAxesInput);
            %colormap(app.UIAxesInput, 'jet');
            %hold(app.UIAxesInput, "off")
            [M,N] = size(app.mask);
            alpha_data = ones(M,N) * app.maskAlpha;
            set(h, 'AlphaData', alpha_data);
            %sset(h, 'AlphaData', alphaImage);
            
            %h = imshowpair(inputImage, app.mask, 'falsecolor', 'ColorChannels', [0 1 2], "Parent", app.UIAxesInput);
            set(h, 'Tag', 'allData')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FileSelectorValueChanged(app, event)
            value = app.FileSelector.Value;
            inputImage = imread(value);
            imshow(inputImage, "Parent", app.UIAxesInput);
        end

        % Button pushed function: OpenInputDirButton
        function OpenInputDirButtonPushed(app, event)
            inputDir = uigetdir;
            inputFilename = GetFirstTifImageFilename(app, inputDir);
            %listing = dir(inputDir);
            %file = listing(3).name;

            %file = uigetfile("*.tif");
            app.inputImage = imread(inputFilename);
            app.mask = rgb2gray(app.inputImage);
            UpdateSegmentation(app);
            get(app.UIAxesInput,'type')
            app.drawNow = 0
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %Create if statement that determines if the user clicked on the
            %line of the top UIAxes. If they didn't, do nothing
            %event.Source.CurrentObject
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'allData')
                  %Store the CurrentPiont of the UIAxes as app.a
                  %app.UIAxesInput.CurrentPoint
                  %pos = get(app.UIAxesInput,'CurrentPoint')
                  %flag_btn=get(event.Source,'SelectionType')
                  app.drawNow = 1

                  flag_btn=get(event.Source,'SelectionType')
                  pos = get(app.UIAxesInput,'CurrentPoint')
                  cm=round(pos(1,2))
                    cn=round(pos(1,1))
                    r = 1;
                    [m,n]=size(app.mask);
                    %if strcmp(flag_btn,'normal')
                    %app.mask(max(cm-r,1):min(cm+r,m),max(cn-r,1):min(cn+r,n))=255; 
                    %elseif strcmp(flag_btn,'alt')
                    %app.mask(max(cm-r,1):min(cm+r,m),max(cn-r,1):min(cn+r,n))=0; 
                    %end
                    app.mask(cm, cn) = 255;
                    UpdateSegmentation(app);

                  %alpha = 200;
                  %Set the XLim of the bottom UIAxes to be +/- alpha of the
                  %current point
                  %app.UIAxes2.XLim = [(app.a-alpha) (app.a+alpha)];
                  %Plot the data on the bottom UIAxes
                  %plot(app.UIAxes2,app.Var1)
                  %UpdateSegmentation(app);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'allData') && app.drawNow == 1
                  %Store the CurrentPiont of the UIAxes as app.a
                  %app.UIAxesInput.CurrentPoint
                  flag_btn=get(event.Source,'SelectionType')
                  pos = get(app.UIAxesInput,'CurrentPoint')
                  app.drawNow = 1
                 % cm=round(pos(1,2))
                   % cn=round(pos(1,1))
                  %  r = 1;
                    %[m,n]=size(app.mask);
                    %if strcmp(flag_btn,'normal')
                    %app.mask(max(cm-r,1):min(cm+r,m),max(cn-r,1):min(cn+r,n))=255; 
                    %elseif strcmp(flag_btn,'alt')
                    %app.mask(max(cm-r,1):min(cm+r,m),max(cn-r,1):min(cn+r,n))=0; 
                    %end
                    %app.mask(cm, cn) = 255;
                    %UpdateSegmentation(app);
                  %flag_btn=get(event.Source,'SelectionType')
                  %alpha = 200;
                  %Set the XLim of the bottom UIAxes to be +/- alpha of the
                  %current point
                  %app.UIAxes2.XLim = [(app.a-alpha) (app.a+alpha)];
                  %Plot the data on the bottom UIAxes
                  %plot(app.UIAxes2,app.Var1)
            end
        end

        % Callback function
        function UIFigureWindowKeyRelease(app, event)

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.drawNow = 0
            UpdateSegmentation(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.maskAlpha = value;
            UpdateSegmentation(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {471, 471, 471};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {471, 471};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {421, '1x', 133};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxesInput = uiaxes(app.LeftPanel);
            app.UIAxesInput.XTick = [];
            app.UIAxesInput.YTick = [];
            app.OpenInputDirButton = uibutton(app.LeftPanel, 'push');
            app.OpenInputDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenInputDirButtonPushed, true);
            app.OpenInputDirButton.Text = 'Open Input Dir';
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantSegOld

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end